classdef kalamari < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RFluxEditField                  matlab.ui.control.NumericEditField
        RFluxEditFieldLabel             matlab.ui.control.Label
        WriteButton                     matlab.ui.control.Button
        ADCDataEditField                matlab.ui.control.NumericEditField
        ADCDataEditFieldLabel           matlab.ui.control.Label
        ADCAddressEditField             matlab.ui.control.NumericEditField
        ADCAddressEditFieldLabel        matlab.ui.control.Label
        ErrorEditField                  matlab.ui.control.NumericEditField
        ErrorEditFieldLabel             matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        ResponseVoltageVEditField       matlab.ui.control.NumericEditField
        ResponseVoltageVEditFieldLabel  matlab.ui.control.Label
        InputVoltagemVEditField         matlab.ui.control.NumericEditField
        InputVoltagemVEditFieldLabel    matlab.ui.control.Label
        RParasiticEditField             matlab.ui.control.NumericEditField
        RParasiticEditFieldLabel        matlab.ui.control.Label
        RShuntEditField                 matlab.ui.control.NumericEditField
        RShuntEditFieldLabel            matlab.ui.control.Label
        RBiasEditField                  matlab.ui.control.NumericEditField
        RBiasEditFieldLabel             matlab.ui.control.Label
        ResistancesLabel                matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        DitherSwitch                    matlab.ui.control.Switch
        DitherSwitchLabel               matlab.ui.control.Label
        SlopeSwitch                     matlab.ui.control.Switch
        SlopeSwitchLabel                matlab.ui.control.Label
        NoiseSwitch                     matlab.ui.control.Switch
        NoiseSwitchLabel                matlab.ui.control.Label
        CurveTypeSwitch                 matlab.ui.control.Switch
        CurveTypeSwitchLabel            matlab.ui.control.Label
        ofStepsEditField_2              matlab.ui.control.NumericEditField
        ofStepsEditField_2Label         matlab.ui.control.Label
        ToEditField_2                   matlab.ui.control.NumericEditField
        ToEditField_2Label              matlab.ui.control.Label
        FromEditField_2                 matlab.ui.control.NumericEditField
        FromEditField_2Label            matlab.ui.control.Label
        BiasAmpsLabel                   matlab.ui.control.Label
        ofStepsEditField                matlab.ui.control.NumericEditField
        ofStepsEditFieldLabel           matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        FluxAmpsLabel                   matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        PageEditField                   matlab.ui.control.EditField
        PageEditFieldLabel              matlab.ui.control.Label
        BookEditField                   matlab.ui.control.EditField
        BookEditFieldLabel              matlab.ui.control.Label
        InitialsEditField               matlab.ui.control.EditField
        InitialsEditFieldLabel          matlab.ui.control.Label
        DieEditField                    matlab.ui.control.EditField
        DieEditFieldLabel               matlab.ui.control.Label
        WaferEditField                  matlab.ui.control.EditField
        WaferEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        a;
        DAC;
        ADC;
    end
    
    methods (Access = private)
        
        function points_array = generatePoints(app,min,max,steps)
            range = max-min;
            interval = range / steps;
            %Sets the initial setpoint as "step 0"
            steps = steps + 1;
            current_value = min;
            points_array = zeros(1,steps);
            for i = 1:steps
                points_array(i)=current_value;
                current_value = current_value + interval;
            end
            % Future: for loop that truncates the elements of the array to
            % the least significant decimal place that the DAC can handle.
        end
        
        function output = getConversionData(app)
            %Prompt a conversion
            writeDigitalPin(app.a,'D52',1);
            writeDigitalPin(app.a,'D52',0);
            data = zeros(1,3);
            data = writeRead(app.ADC,data);
            %This ugly thing converts the bit output into a single value
            output = data(1)*2^10 + data(2)*2^2 + data(3)*2^-6;
            %1 LSB is equal to 76.3 microvolts on the 7606 (pg.38)
            output = output / 13107.2;
            %Check for 2's complement
            if bitand(data(1),0x80) ~= 0
                output = output - 20;
            end
        end
        
        function setDAC(app,dac,voltage)
            %This converts the input to a 2's complement value.
            %Results will effectively round down to the nearest LSB
            %1 LSB = 0.305mV
            data = uint16(3276.8*(voltage + 10));
            %This puts our input into single byte blocks for transfer.
            upperbyte = bitshift(bitand(data,0xFF00),-8);
            lowerbyte = bitand(data,0x00FF);
            %DAC A = 0, DAC B = 1, etc
            dac = dac - 1;
            %Gets the address for the data register of the specific DAC
            address = bitor(dac,0x10);
            dataIn = [address,upperbyte,lowerbyte];
            writeRead(app.DAC,dataIn);
        end
        
        function kalamari_data = getKalamariData(app,inner_loop,outer_loop,dither,noise)
            kalamari_data = {};
            %(numel returns number of elements in an array)
            for i = 1:numel(outer_loop)
                setDAC(app,1,outer_loop(i));
                for j = 1:numel(inner_loop)
                    setDAC(app,4,inner_loop(j));
                    readout = getConversionData(app);
                    
                    if dither ~= 0
                        dither = 0.00031*dither;
                        setDAC(app,4,inner_loop(j)-dither);
                        dither_left = getConversionData(app);
                        
                        setDAC(app,4,inner_loop(j)+dither);
                        dither_right = getConversionData(app);
                        
                        %Reset voltage
                        setDAC(app,4,inner_loop(j));
                        
                        setDAC(app,1,outer_loop(i)+dither);
                        dither_up = getConversionData(app);
                        
                        setDAC(app,1,outer_loop(i)-dither);
                        dither_down = getConversionData(app);
                        
                        %Reset voltage
                        setDAC(app,1,outer_loop(i));
                        
                        inner_slope = (dither_right - dither_left) / (dither*2);
                        outer_slope = (dither_up - dither_down) / (dither*2);
                    else
                        inner_slope = 0;
                        outer_slope = 0;
                    end
                    if noise == true
                        %do something that gets the noise???
                    end
                    %Add an entry to the data array
                    kalamari_data = horzcat(kalamari_data,[outer_loop(i),inner_loop(j),readout,inner_slope,outer_slope]);
                end
                
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a=arduino('COM6','Due','Libraries','SPI');
            app.DAC = device(app.a,'SPIChipSelectPin','D22','SPIMode',1);
            app.ADC = device(app.a,'SPIChipSelectPin','D46','SPIMode',2);
            writeDigitalPin(app.a,'D50',1);
            writeDigitalPin(app.a,'D50',0);
            %Set data output to all data lines on the ADC; channels 2-8 are unloaded
            %and we don't want to output data sequentially since we'd just have 7 entries of 0
            writeRead(app.ADC,[0x02,0x18]);
            %The ADC has a standard error of 3-4% without a correction.
            %This sets the the gain register for channel 1 to ~53kΩ
            writeRead(app.ADC,[0x09,0x3B]);
            %Zero out the DAC outputs
            setDAC(app,5,0);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           
            %Get parameters from front end
            flux_min = app.FromEditField.Value;
            flux_max = app.ToEditField.Value;
            flux_steps = app.ofStepsEditField.Value;
            bias_min = app.FromEditField_2.Value;
            bias_max = app.ToEditField_2.Value;
            bias_steps = app.ofStepsEditField_2.Value;
            
            r_p = app.RParasiticEditField.Value;
            r_shunt = app.RShuntEditField.Value;
            r_bias = app.RBiasEditField.Value;
            r_flux = app.RFluxEditField.Value;
            
            wafer = app.WaferEditField.Value;
            die = app.DieEditField.Value;
            initials = app.InitialsEditField.Value;
            book = app.BookEditField.Value;
            page = app.PageEditField.Value;
            
            dither = app.DitherSwitch.Value;
            get_slope = (app.SlopeSwitch.Value == 1);
            get_noise = (app.NoiseSwitch.Value == 1);
            v_phi = (app.CurveTypeSwitch.Value == 0);
            
            %Process intermediate variables
            
            %Get string for records
            chip_name = append(wafer,die);
            book_info = append(initials,"Book: ",book," Page: ",page);
            
            %Convert bias/flux current values into volts (Book 3 pg 55)
            flux_min_v = flux_min * r_flux / 1000000;
            flux_max_v = flux_max * r_flux / 1000000;
            bias_min_v = bias_min * (r_bias + (r_p^-1 + r_shunt^-1)^-1) / 1000000;
            bias_max_v = bias_max * (r_bias + (r_p^-1 + r_shunt^-1)^-1) / 1000000;
            
            %Get an array of the points to test with
            bias_points = generatePoints(app,bias_min_v,bias_max_v,bias_steps);
            flux_points = generatePoints(app,flux_min_v,flux_max_v,flux_steps);
            bias_points
            flux_points
            
            if ~get_slope
                dither = 0;
            end
            
            if v_phi == true
                kalamari_data = getKalamariData(app,bias_points,flux_points,dither,get_noise);
            else
                kalamari_data = getKalamariData(app,flux_points,bias_points,dither,get_noise);
            end
            
            
            kalamari_data
            %Zero out the DAC outputs on completion
            setDAC(app,5,0);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            voltage = app.InputVoltagemVEditField.Value / 1000;
            setDAC(app,1,voltage);
            adc_output = getConversionData(app);
            app.ResponseVoltageVEditField.Value = adc_output;
            app.ErrorEditField.Value = 1 - adc_output / voltage;
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            address = app.ADCAddressEditField.Value;
            data = app.ADCDataEditField.Value;
            writeRead(app.ADC,[address,data]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WaferEditFieldLabel = uilabel(app.UIFigure);
            app.WaferEditFieldLabel.Text = 'Wafer:';
            app.WaferEditField = uieditfield(app.UIFigure, 'text');
            app.DieEditFieldLabel = uilabel(app.UIFigure);
            app.DieEditFieldLabel.Text = 'Die:';
            app.DieEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials:';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.BookEditFieldLabel = uilabel(app.UIFigure);
            app.BookEditFieldLabel.Text = 'Book:';
            app.BookEditField = uieditfield(app.UIFigure, 'text');
            app.PageEditFieldLabel = uilabel(app.UIFigure);
            app.PageEditFieldLabel.Text = 'Page:';
            app.PageEditField = uieditfield(app.UIFigure, 'text');
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.Value = 50;
            app.FluxAmpsLabel = uilabel(app.UIFigure);
            app.FluxAmpsLabel.Text = 'Flux (μAmps)';
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.Value = 60;
            app.ofStepsEditFieldLabel = uilabel(app.UIFigure);
            app.ofStepsEditFieldLabel.Text = '# of Steps';
            app.ofStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofStepsEditField.Value = 5;
            app.BiasAmpsLabel = uilabel(app.UIFigure);
            app.BiasAmpsLabel.Text = 'Bias (μAmps)';
            app.FromEditField_2Label = uilabel(app.UIFigure);
            app.FromEditField_2Label.Text = 'From:';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField_2.Value = 30;
            app.ToEditField_2Label = uilabel(app.UIFigure);
            app.ToEditField_2Label.Text = 'To:';
            app.ToEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField_2.Value = 45;
            app.ofStepsEditField_2Label = uilabel(app.UIFigure);
            app.ofStepsEditField_2Label.Text = '# of Steps';
            app.ofStepsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ofStepsEditField_2.Value = 4;
            app.CurveTypeSwitchLabel = uilabel(app.UIFigure);
            app.CurveTypeSwitchLabel.Text = 'Curve Type';
            app.CurveTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.CurveTypeSwitch.Items = {'V/Φ', 'I/V'};
            app.CurveTypeSwitch.Value = 'V/Φ';
            app.NoiseSwitchLabel = uilabel(app.UIFigure);
            app.NoiseSwitchLabel.Text = 'Noise';
            app.NoiseSwitch = uiswitch(app.UIFigure, 'slider');
            app.NoiseSwitch.ItemsData = [0 1];
            app.NoiseSwitch.Value = 0;
            app.SlopeSwitchLabel = uilabel(app.UIFigure);
            app.SlopeSwitchLabel.Text = 'Slope';
            app.SlopeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SlopeSwitch.ItemsData = [0 1];
            app.SlopeSwitch.Value = 0;
            app.DitherSwitchLabel = uilabel(app.UIFigure);
            app.DitherSwitchLabel.Text = 'Dither';
            app.DitherSwitch = uiswitch(app.UIFigure, 'slider');
            app.DitherSwitch.Items = {'1', '2'};
            app.DitherSwitch.ItemsData = [1 2];
            app.DitherSwitch.Value = 1;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ResistancesLabel = uilabel(app.UIFigure);
            app.ResistancesLabel.Text = 'Resistances';
            app.RBiasEditFieldLabel = uilabel(app.UIFigure);
            app.RBiasEditFieldLabel.Text = 'R-Bias';
            app.RBiasEditField = uieditfield(app.UIFigure, 'numeric');
            app.RBiasEditField.ValueDisplayFormat = '%.0f';
            app.RBiasEditField.Value = 10000;
            app.RShuntEditFieldLabel = uilabel(app.UIFigure);
            app.RShuntEditFieldLabel.Text = 'R-Shunt';
            app.RShuntEditField = uieditfield(app.UIFigure, 'numeric');
            app.RParasiticEditFieldLabel = uilabel(app.UIFigure);
            app.RParasiticEditFieldLabel.Text = 'R-Parasitic';
            app.RParasiticEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputVoltagemVEditFieldLabel = uilabel(app.UIFigure);
            app.InputVoltagemVEditFieldLabel.Text = 'Input Voltage (mV)';
            app.InputVoltagemVEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResponseVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.ResponseVoltageVEditFieldLabel.Text = 'Response Voltage (V)';
            app.ResponseVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ADCAddressEditFieldLabel = uilabel(app.UIFigure);
            app.ADCAddressEditFieldLabel.Text = 'ADC Address';
            app.ADCAddressEditField = uieditfield(app.UIFigure, 'numeric');
            app.ADCDataEditFieldLabel = uilabel(app.UIFigure);
            app.ADCDataEditFieldLabel.Text = 'ADC Data';
            app.ADCDataEditField = uieditfield(app.UIFigure, 'numeric');
            app.WriteButton = uibutton(app.UIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.RFluxEditFieldLabel = uilabel(app.UIFigure);
            app.RFluxEditFieldLabel.Text = 'R-Flux';
            app.RFluxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RFluxEditField.ValueDisplayFormat = '%.0f';
            app.RFluxEditField.Value = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kalamari

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end